classdef finalProject < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        BegindetectionButton  matlab.ui.control.Button
        LoadFolderButton      matlab.ui.control.Button
        UIAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        MainDir; % Main directory of the images & data
        BinImages;
        BBoxes;
        Tracks;
        Covariances;
    end
    
    methods (Access = private)
        
        function BinImage = ObjectDetection(app, frames)
            % Step a
        end
        
        function BBoxes = ObjectDiscrimination(app, binImage)
            % Step b
        end
        
        function [assignedTracks, unassignedTracks, assignedDetections] = TrackHypothesis(app, bboxes)
            % Step c/e
        end
        
        function display = KalmanFilter(app, assignedTracks, unassignedTracks, assignedDetections)
            % Step d/f/g
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadFolderButton
        function LoadFolderButtonPushed(app, event)
            % Load images
            % Directory as global property
            % Display first one?
        end

        % Button pushed function: BegindetectionButton
        function BegindetectionButtonPushed(app, event)
            % Object Detection
            app.BinImages = ObjectDetection(app);
            % Object Discrimination
            app.BBoxes = ObjectDiscrimination(app);
            for frame = size(app.BBoxes, 1)
                %Hypothesis
                app.tracks = TrackHypothesis(app, app.bboxes);
                %Kalman filter
                app.display = KalmanFilter(app, app.tracks(1), app.tracks(2), app.tracks(3));
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.Visible = 'off';
            app.LoadFolderButton = uibutton(app.UIFigure, 'push');
            app.LoadFolderButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFolderButtonPushed, true);
            app.LoadFolderButton.Text = 'Load Folder';
            app.BegindetectionButton = uibutton(app.UIFigure, 'push');
            app.BegindetectionButton.ButtonPushedFcn = createCallbackFcn(app, @BegindetectionButtonPushed, true);
            app.BegindetectionButton.Text = 'Begin detection';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = finalProject

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end